classdef GUIPanelE05L < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        q6Slider                      matlab.ui.control.Slider
        q6Label                       matlab.ui.control.Label
        estopLamp                     matlab.ui.control.Lamp
        ESTOPLampLabel                matlab.ui.control.Label
        q5Slider                      matlab.ui.control.Slider
        q5SliderLabel                 matlab.ui.control.Label
        q4Slider                      matlab.ui.control.Slider
        q4SliderLabel                 matlab.ui.control.Label
        q3Slider                      matlab.ui.control.Slider
        q3SliderLabel                 matlab.ui.control.Label
        q2Slider                      matlab.ui.control.Slider
        q2SliderLabel                 matlab.ui.control.Label
        q1Slider                      matlab.ui.control.Slider
        q1SliderLabel_7               matlab.ui.control.Label
        zRotationSlider               matlab.ui.control.Slider
        yRotationSlider               matlab.ui.control.Slider
        xRotationSlider               matlab.ui.control.Slider
        zrSliderLabel                 matlab.ui.control.Label
        yrSliderLabel                 matlab.ui.control.Label
        xrSliderLabel                 matlab.ui.control.Label
        zTranslationSlider            matlab.ui.control.Slider
        yTranslationSlider            matlab.ui.control.Slider
        xTranslationSlider            matlab.ui.control.Slider
        ztSliderLabel                 matlab.ui.control.Label
        ytSliderLabel                 matlab.ui.control.Label
        xtSliderLabel                 matlab.ui.control.Label
        ElfinE05_LPoseToggleControlsLabel  matlab.ui.control.Label
        ElfinE05_LEmergencyStopLabel  matlab.ui.control.Label
        ELFINE05_LGUIPANELLabel       matlab.ui.control.Label
        estopReleaseButton            matlab.ui.control.Button
        estopButton                   matlab.ui.control.Button
        ElfinE05_LJointAnglesqvaluesLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        % Properties for robot emergency stop buttons
        estop_activated = 0;
        estop_deactivated = 0;

        q = zeros(1,6);

        vx
        vy
        vz
        wx
        wy
        wz

        myTimer

    end
    methods (Access = private)

        function updateJoy(app,~,~)
            wrench = zeros(6,1);
            wrench(1) = app.vx;
            wrench(2) = app.vy;
            wrench(3) = app.vz;
            wrench(4) = app.wx;
            wrench(5) = app.wy;
            wrench(6) = app.wz;

            assignin('base','wrench',wrench);
            %evalin('base','who');
            %wrench = [app.vx; app.vy; app.vz; app.wx; app.wy; app.wz];
            %disp(wrench);
        end
        % function updateQ(app,~,~)
        %     jointAngle = zeros(1,6);
        %     jointAngle(1) = app.xTranslationSlider.Value;
        %     jointAngle(2) = app.yTranslationSlider.Value;
        %     jointAngle(3) = app.zTranslationSlider.Value;
        %     jointAngle(4) = app.xRotationSlider.Value;
        %     jointAngle(5) = app.yRotationSlider.Value;
        %     jointAngle(6) = app.zRotationSlider.Value;
        % 
        %     assignin('base','jointAngle',jointAngle);
        % end

    end

    methods (Access = public)

        function wrench = read(app)
            wrench = zeros(6,1);
            wrench(1) = app.vx;
            wrench(2) = app.vy;
            wrench(3) = app.vz;
            wrench(4) = app.wx;
            wrench(5) = app.wy;
            wrench(6) = app.wz;
        end
        function setJointAngles(app, q)
            app.q1Slider.Value = (q(1))
            app.q2Slider.Value = (q(2))
            app.q3Slider.Value = (q(3))
            app.q4Slider.Value = (q(4))
            app.q5Slider.Value = (q(5))
            app.q6Slider.Value = (q(6))
        end
        % function jointAngle = readQ(app)
        %     jointAngle = zeros(1,6);
        %     jointAngle(1) = app.xTranslationSlider.Value;
        %     jointAngle(2) = app.yTranslationSlider.Value;
        %     jointAngle(3) = app.zTranslationSlider.Value;
        %     jointAngle(4) = app.xRotationSlider.Value;
        %     jointAngle(5) = app.yRotationSlider.Value;
        %     jointAngle(6) = app.zRotationSlider.Value;
        % end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set initial value based on Elfin E05_L current joint angles
            app.q1Slider.Value = app.q(1); 
            app.q2Slider.Value = app.q(2);
            app.q3Slider.Value = app.q(3);
            app.q4Slider.Value = app.q(4);
            app.q5Slider.Value = app.q(5);
            app.q6Slider.Value = app.q(6);

            app.vx = 0;     % velocity in the x direction
            app.vy = 0;     % velocity in the y direction
            app.vz = 0;     % velocity in the z direction
            app.wx = 0;     % angular velocity about x
            app.wy = 0;     % angular velocity about y
            app.wz = 0;     % angular velocity about z

            app.myTimer = timer('Period',0.05,...
            'ExecutionMode', 'fixedSpacing', ...
            'TasksToExecute', Inf);
            
            app.myTimer.TimerFcn = @(~,~)app.updateJoy;
        end

        % Button pushed function: estopButton
        function estopButtonPushed(app, event)
            app.estop_activated = true;            % flag set to indicate emergency stop for Elfin E05_L has been activated
            app.estopLamp.Color = [1, 0, 0];       % Changes lamp colour to red to indicate estop has been activated
        end

        % Button pushed function: estopReleaseButton
        function estopReleaseButtonPushed(app, event)
            app.estop_deactivated = true;          % flag set to indicate emergency stop for E05L has been deactivated
            app.estopLamp.Color = [1, 1, 1];       % Changes lamp colour to white to indicate E05L estop has been deactivated
        end

        % Value changed function: q1Slider
        function q1SliderValueChanged(app, event)
            newQvalue = app.q1Slider.Value;
            app.q(1) = newQvalue;
        end

        % Value changed function: q2Slider
        function q2SliderValueChanged(app, event)
            newQvalue = app.q2Slider.Value;
            app.q(2) = newQvalue;
        end

        % Value changed function: q3Slider
        function q3SliderValueChanged(app, event)
            newQvalue = app.q3Slider.Value;
            app.q(3) = newQvalue;
        end

        % Value changed function: q4Slider
        function q4SliderValueChanged(app, event)
            newQvalue = app.q4Slider.Value;
            app.q(4) = newQvalue;
        end

        % Value changed function: q5Slider
        function q5SliderValueChanged(app, event)
            newQvalue = app.q5Slider.Value;
            app.q(5) = newQvalue;
        end

        % Value changed function: q6Slider
        function q6SliderValueChanged(app, event)
            newQvalue = app.q6Slider.Value;
            app.q(6) = newQvalue;
        end

        % Value changing function: q1Slider
        function q1SliderValueChanging(app, event)
            changingValue = event.Value;            % Current q1 slider value
            app.q1Slider.Value = changingValue;     % update q1 value
        end

        % Value changing function: q2Slider
        function q2SliderValueChanging(app, event)
            changingValue = event.Value;            % Current q2 slider value
            app.q2Slider.Value = changingValue;     % update q2 value
        end

        % Value changing function: q3Slider
        function q3SliderValueChanging(app, event)
            changingValue = event.Value;            % Current q3 slider value
            app.q3Slider.Value = changingValue;     % update q3 value
        end

        % Value changing function: q4Slider
        function q4SliderValueChanging(app, event)
            changingValue = event.Value;            % Current q4 slider value
            app.q4Slider.Value = changingValue;     % update q4 value
        end

        % Value changing function: q5Slider
        function q5SliderValueChanging(app, event)
            changingValue = event.Value;            % Current q5 slider value
            app.q5Slider.Value = changingValue;     % update q5 value
        end

        % Value changing function: q6Slider
        function q6SliderValueChanging(app, event)
            changingValue = event.Value;            % Current q6 slider value
            app.q6Slider.Value = changingValue;     % update q6 value
        end

        % Value changed function: xTranslationSlider
        function xTranslationSliderValueChanged(app, event)
            app.xTranslationSlider.Value = 0;
            app.vx = 0;
        end

        % Value changed function: yTranslationSlider
        function yTranslationSliderValueChanged(app, event)
            app.yTranslationSlider.Value = 0;
            app.vy = 0;
        end

        % Value changed function: zTranslationSlider
        function zTranslationSliderValueChanged(app, event)
            app.zTranslationSlider.Value = 0;
            app.vz = 0;
        end

        % Value changed function: xRotationSlider
        function xRotationSliderValueChanged(app, event)
            app.xRotationSlider.Value = 0;
            app.wx = 0;
        end

        % Value changed function: yRotationSlider
        function yRotationSliderValueChanged(app, event)
            app.yRotationSlider.Value = 0;
            app.wy = 0;
        end

        % Value changed function: zRotationSlider
        function zRotationSliderValueChanged(app, event)
            app.zRotationSlider.Value = 0;
            app.wz = 0;
        end

        % Value changing function: xTranslationSlider
        function xTranslationSliderValueChanging(app, event)
            app.vx = event.Value;
        end

        % Value changing function: yTranslationSlider
        function yTranslationSliderValueChanging(app, event)
            app.vy = event.Value;
        end

        % Value changing function: zTranslationSlider
        function zTranslationSliderValueChanging(app, event)
            app.vz = event.Value;
        end

        % Value changing function: xRotationSlider
        function xRotationSliderValueChanging(app, event)
            app.wx = event.Value;
        end

        % Value changing function: yRotationSlider
        function yRotationSliderValueChanging(app, event)
            app.wy = event.Value;
        end

        % Value changing function: zRotationSlider
        function zRotationSliderValueChanging(app, event)
            app.wz = event.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ElfinE05_LJointAnglesqvaluesLabel = uilabel(app.UIFigure);
            app.ElfinE05_LJointAnglesqvaluesLabel.Text = 'Elfin E05_L Joint Angles (q values)';
            app.estopButton = uibutton(app.UIFigure, 'push');
            app.estopButton.ButtonPushedFcn = createCallbackFcn(app, @estopButtonPushed, true);
            app.estopButton.Text = 'ESTOP Elfin E05_L';
            app.estopReleaseButton = uibutton(app.UIFigure, 'push');
            app.estopReleaseButton.ButtonPushedFcn = createCallbackFcn(app, @estopReleaseButtonPushed, true);
            app.estopReleaseButton.Text = 'Release Elfin E05_L ESTOP';
            app.ELFINE05_LGUIPANELLabel = uilabel(app.UIFigure);
            app.ELFINE05_LGUIPANELLabel.Text = 'ELFIN E05_L GUI PANEL';
            app.ElfinE05_LEmergencyStopLabel = uilabel(app.UIFigure);
            app.ElfinE05_LEmergencyStopLabel.Text = 'Elfin E05_L Emergency Stop';
            app.ElfinE05_LPoseToggleControlsLabel = uilabel(app.UIFigure);
            app.ElfinE05_LPoseToggleControlsLabel.Text = 'Elfin E05_L Pose Toggle Controls';
            app.xtSliderLabel = uilabel(app.UIFigure);
            app.xtSliderLabel.Text = 'x  translation';
            app.ytSliderLabel = uilabel(app.UIFigure);
            app.ytSliderLabel.Text = 'y  translation';
            app.ztSliderLabel = uilabel(app.UIFigure);
            app.ztSliderLabel.Text = 'z  translation';
            app.xTranslationSlider = uislider(app.UIFigure);
            app.xTranslationSlider.Limits = [-1 1];
            app.xTranslationSlider.MajorTicks = [-1 -0.5 0 0.5 1];
            app.xTranslationSlider.ValueChangedFcn = createCallbackFcn(app, @xTranslationSliderValueChanged, true);
            app.xTranslationSlider.ValueChangingFcn = createCallbackFcn(app, @xTranslationSliderValueChanging, true);
            app.yTranslationSlider = uislider(app.UIFigure);
            app.yTranslationSlider.Limits = [-1 1];
            app.yTranslationSlider.MajorTicks = [-1 -0.5 0 0.5 1];
            app.yTranslationSlider.ValueChangedFcn = createCallbackFcn(app, @yTranslationSliderValueChanged, true);
            app.yTranslationSlider.ValueChangingFcn = createCallbackFcn(app, @yTranslationSliderValueChanging, true);
            app.zTranslationSlider = uislider(app.UIFigure);
            app.zTranslationSlider.Limits = [-1 1];
            app.zTranslationSlider.MajorTicks = [-1 -0.5 0 0.5 1];
            app.zTranslationSlider.ValueChangedFcn = createCallbackFcn(app, @zTranslationSliderValueChanged, true);
            app.zTranslationSlider.ValueChangingFcn = createCallbackFcn(app, @zTranslationSliderValueChanging, true);
            app.xrSliderLabel = uilabel(app.UIFigure);
            app.xrSliderLabel.Text = 'x rotation';
            app.yrSliderLabel = uilabel(app.UIFigure);
            app.yrSliderLabel.Text = 'y rotation';
            app.zrSliderLabel = uilabel(app.UIFigure);
            app.zrSliderLabel.Text = 'z rotation';
            app.xRotationSlider = uislider(app.UIFigure);
            app.xRotationSlider.Limits = [-1 1];
            app.xRotationSlider.MajorTicks = [-1 -0.5 0 0.5 1];
            app.xRotationSlider.ValueChangedFcn = createCallbackFcn(app, @xRotationSliderValueChanged, true);
            app.xRotationSlider.ValueChangingFcn = createCallbackFcn(app, @xRotationSliderValueChanging, true);
            app.yRotationSlider = uislider(app.UIFigure);
            app.yRotationSlider.Limits = [-1 1];
            app.yRotationSlider.MajorTicks = [-1 -0.5 0 0.5 1];
            app.yRotationSlider.ValueChangedFcn = createCallbackFcn(app, @yRotationSliderValueChanged, true);
            app.yRotationSlider.ValueChangingFcn = createCallbackFcn(app, @yRotationSliderValueChanging, true);
            app.zRotationSlider = uislider(app.UIFigure);
            app.zRotationSlider.Limits = [-1 1];
            app.zRotationSlider.MajorTicks = [-1 -0.5 0 0.5 1];
            app.zRotationSlider.ValueChangedFcn = createCallbackFcn(app, @zRotationSliderValueChanged, true);
            app.zRotationSlider.ValueChangingFcn = createCallbackFcn(app, @zRotationSliderValueChanging, true);
            app.q1SliderLabel_7 = uilabel(app.UIFigure);
            app.q1SliderLabel_7.Text = 'q1';
            app.q1Slider = uislider(app.UIFigure);
            app.q1Slider.Limits = [-360 360];
            app.q1Slider.MajorTicks = [-360 -270 -180 -90 0 90 180 270 360];
            app.q1Slider.ValueChangedFcn = createCallbackFcn(app, @q1SliderValueChanged, true);
            app.q1Slider.ValueChangingFcn = createCallbackFcn(app, @q1SliderValueChanging, true);
            app.q1Slider.MinorTicks = [-360 -315 -270 -225 -180 -135 -90 -45 0 45 90 135 180 225 270 315 360];
            app.q2SliderLabel = uilabel(app.UIFigure);
            app.q2SliderLabel.Text = 'q2';
            app.q2Slider = uislider(app.UIFigure);
            app.q2Slider.Limits = [-360 360];
            app.q2Slider.MajorTicks = [-360 -270 -180 -90 0 90 180 270 360];
            app.q2Slider.ValueChangedFcn = createCallbackFcn(app, @q2SliderValueChanged, true);
            app.q2Slider.ValueChangingFcn = createCallbackFcn(app, @q2SliderValueChanging, true);
            app.q2Slider.MinorTicks = [-360 -315 -270 -225 -180 -135 -90 -45 0 45 90 135 180 225 270 315 360];
            app.q3SliderLabel = uilabel(app.UIFigure);
            app.q3SliderLabel.Text = 'q3';
            app.q3Slider = uislider(app.UIFigure);
            app.q3Slider.Limits = [-360 360];
            app.q3Slider.MajorTicks = [-360 -270 -180 -90 0 90 180 270 360];
            app.q3Slider.ValueChangedFcn = createCallbackFcn(app, @q3SliderValueChanged, true);
            app.q3Slider.ValueChangingFcn = createCallbackFcn(app, @q3SliderValueChanging, true);
            app.q3Slider.MinorTicks = [-360 -315 -270 -225 -180 -135 -90 -45 0 45 90 135 180 225 270 315 360];
            app.q4SliderLabel = uilabel(app.UIFigure);
            app.q4SliderLabel.Text = 'q4';
            app.q4Slider = uislider(app.UIFigure);
            app.q4Slider.Limits = [-360 360];
            app.q4Slider.MajorTicks = [-360 -270 -180 -90 0 90 180 270 360];
            app.q4Slider.ValueChangedFcn = createCallbackFcn(app, @q4SliderValueChanged, true);
            app.q4Slider.ValueChangingFcn = createCallbackFcn(app, @q4SliderValueChanging, true);
            app.q4Slider.MinorTicks = [-360 -315 -270 -225 -180 -135 -90 -45 0 45 90 135 180 225 270 315 360];
            app.q5SliderLabel = uilabel(app.UIFigure);
            app.q5SliderLabel.Text = 'q5';
            app.q5Slider = uislider(app.UIFigure);
            app.q5Slider.Limits = [-360 360];
            app.q5Slider.MajorTicks = [-360 -270 -180 -90 0 90 180 270 360];
            app.q5Slider.ValueChangedFcn = createCallbackFcn(app, @q5SliderValueChanged, true);
            app.q5Slider.ValueChangingFcn = createCallbackFcn(app, @q5SliderValueChanging, true);
            app.q5Slider.MinorTicks = [-360 -315 -270 -225 -180 -135 -90 -45 0 45 90 135 180 225 270 315 360];
            app.ESTOPLampLabel = uilabel(app.UIFigure);
            app.ESTOPLampLabel.Text = 'E-STOP Lamp';
            app.estopLamp = uilamp(app.UIFigure);
            app.q6Label = uilabel(app.UIFigure);
            app.q6Label.Text = 'q6';
            app.q6Slider = uislider(app.UIFigure);
            app.q6Slider.Limits = [-360 360];
            app.q6Slider.MajorTicks = [-360 -270 -180 -90 0 90 180 270 360];
            app.q6Slider.ValueChangedFcn = createCallbackFcn(app, @q6SliderValueChanged, true);
            app.q6Slider.ValueChangingFcn = createCallbackFcn(app, @q6SliderValueChanging, true);
            app.q6Slider.MinorTicks = [-360 -315 -270 -225 -180 -135 -90 -45 0 45 90 135 180 225 270 315 360];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIPanelE05L

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end