classdef MaestroGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        RestockSwitch           matlab.ui.control.Switch
        RestockSwitchLabel      matlab.ui.control.Label
        CancelOrderButton       matlab.ui.control.Button
        TheUnEStopButton        matlab.ui.control.Button
        PowerLamp               matlab.ui.control.Lamp
        PowerLampLabel          matlab.ui.control.Label
        SendOrderButton         matlab.ui.control.Button
        OrderAmountSlider       matlab.ui.control.Slider
        OrderAmountSliderLabel  matlab.ui.control.Label
        JuiceTypeButtonGroup    matlab.ui.container.ButtonGroup
        BlackCurrantButton      matlab.ui.control.ToggleButton
        OrangeButton            matlab.ui.control.ToggleButton
        MealTypeButtonGroup     matlab.ui.container.ButtonGroup
        MeatButton              matlab.ui.control.ToggleButton
        VegetarianButton        matlab.ui.control.ToggleButton
        ContinueOrderButton     matlab.ui.control.Button
        PowerRobotsButton       matlab.ui.control.Button
        EstopLamp               matlab.ui.control.Lamp
        EstopLampLabel          matlab.ui.control.Label
        EStopButton             matlab.ui.control.Button
    end


    properties (Access = public)

    end

    properties (Access = private)
        maestro = Maestro()
        mealType;
        juiceType;
        orderAmount;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.mealType = 'm';
            app.juiceType = 'b';
            app.orderAmount = '1';
        end

        % Button pushed function: EStopButton
        function EStopButtonPushed(app, event)
            app.maestro.EStopOn
            app.EstopLamp.Color = [1 0 0];
            app.PowerLamp.Color = [1 1 1];
        end

        % Selection changed function: MealTypeButtonGroup
        function MealTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.MealTypeButtonGroup.SelectedObject;
            if selectedButton == app.MeatButton
                app.mealType = 'meat';
            else
                app.mealType = 'v';
            end
        end

        % Selection changed function: JuiceTypeButtonGroup
        function JuiceTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.JuiceTypeButtonGroup.SelectedObject;
            if selectedButton == app.OrangeButton
                app.juiceType = 'o';
            else
                app.juiceType = 'b';
            end
        end

        % Value changed function: OrderAmountSlider
        function OrderAmountSliderValueChanged(app, event)
            value = app.OrderAmountSlider.Value;
            app.orderAmount = floor(value);
        end

        % Button pushed function: SendOrderButton
        function SendOrderButtonPushed(app, event)
            if app.maestro.totalMealCount < app.maestro.currentMealCount
                app.maestro.MealOrders(app.mealType,app.juiceType,app.orderAmount)
            else
            end
        end

        % Button pushed function: TheUnEStopButton
        function TheUnEStopButtonPushed(app, event)
            app.maestro.EStopOff;
            app.EstopLamp.Color = [1 1 1];
        end

        % Button pushed function: PowerRobotsButton
        function PowerRobotsButtonPushed(app, event)
            if app.maestro.eButton == 0
                app.maestro.RobotsOn;
                app.PowerLamp.Color = [0 1 0];
            else
            end
        end

        % Button pushed function: ContinueOrderButton
        function ContinueOrderButtonPushed(app, event)
            if app.maestro.eButton == 0
                app.maestro.ContinueOrder;
            end
        end

        % Button pushed function: CancelOrderButton
        function CancelOrderButtonPushed(app, event)
            %Tells maestro to wrap up the order it's doing and then stop
            %producing any more
            if (app.maestro.currentMealCount < app.maestro.totalMealCount)
                app.maestro.WrapUpOrder()
            else
            end
        end

        % Callback function: not associated with a component
        function ResetWorkspaceButtonPushed(app, event)
            clear
            close
            clc
            app.mealType = 'm';
            app.juiceType = 'b';
            app.orderAmount = '1';
            app.maestro = Maestro();
        end

        % Callback function: not associated with a component
        function RestockTraysButtonPushed(app, event)

        end

        % Value changed function: RestockSwitch
        function RestockSwitchValueChanged(app, event)
            value = app.RestockSwitch.Value;
            switch value
                case 'Leave'
                    app.maestro.WorkerLeaveWorkspace;
                case 'Enter'
                    app.maestro.WorkerEnterWorkspace;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EStopButton = uibutton(app.UIFigure, 'push');
            app.EStopButton.ButtonPushedFcn = createCallbackFcn(app, @EStopButtonPushed, true);
            app.EStopButton.Text = {'E-Stop '; 'Button'};
            app.EstopLampLabel = uilabel(app.UIFigure);
            app.EstopLampLabel.Text = 'Estop';
            app.EstopLamp = uilamp(app.UIFigure);
            app.PowerRobotsButton = uibutton(app.UIFigure, 'push');
            app.PowerRobotsButton.ButtonPushedFcn = createCallbackFcn(app, @PowerRobotsButtonPushed, true);
            app.PowerRobotsButton.Text = 'Power Robots';
            app.ContinueOrderButton = uibutton(app.UIFigure, 'push');
            app.ContinueOrderButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueOrderButtonPushed, true);
            app.ContinueOrderButton.Text = 'Continue Order';
            app.MealTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.MealTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MealTypeButtonGroupSelectionChanged, true);
            app.MealTypeButtonGroup.Title = 'Meal Type';
            app.VegetarianButton = uitogglebutton(app.MealTypeButtonGroup);
            app.VegetarianButton.Text = 'Vegetarian';
            app.VegetarianButton.Value = true;
            app.MeatButton = uitogglebutton(app.MealTypeButtonGroup);
            app.MeatButton.Text = 'Meat';
            app.JuiceTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.JuiceTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @JuiceTypeButtonGroupSelectionChanged, true);
            app.JuiceTypeButtonGroup.Title = 'Juice Type';
            app.OrangeButton = uitogglebutton(app.JuiceTypeButtonGroup);
            app.OrangeButton.Text = 'Orange';
            app.OrangeButton.Value = true;
            app.BlackCurrantButton = uitogglebutton(app.JuiceTypeButtonGroup);
            app.BlackCurrantButton.Text = 'Black Currant';
            app.OrderAmountSliderLabel = uilabel(app.UIFigure);
            app.OrderAmountSliderLabel.Text = 'Order Amount';
            app.OrderAmountSlider = uislider(app.UIFigure);
            app.OrderAmountSlider.Limits = [1 8];
            app.OrderAmountSlider.MajorTicks = [1 2 3 4 5 6 7 8];
            app.OrderAmountSlider.ValueChangedFcn = createCallbackFcn(app, @OrderAmountSliderValueChanged, true);
            app.OrderAmountSlider.MinorTicks = [1 2 3 4 5 6 7 8];
            app.OrderAmountSlider.Value = 1;
            app.SendOrderButton = uibutton(app.UIFigure, 'push');
            app.SendOrderButton.ButtonPushedFcn = createCallbackFcn(app, @SendOrderButtonPushed, true);
            app.SendOrderButton.Text = 'Send Order';
            app.PowerLampLabel = uilabel(app.UIFigure);
            app.PowerLampLabel.Text = 'Power';
            app.PowerLamp = uilamp(app.UIFigure);
            app.TheUnEStopButton = uibutton(app.UIFigure, 'push');
            app.TheUnEStopButton.ButtonPushedFcn = createCallbackFcn(app, @TheUnEStopButtonPushed, true);
            app.TheUnEStopButton.Text = 'The Un E-Stop Button';
            app.CancelOrderButton = uibutton(app.UIFigure, 'push');
            app.CancelOrderButton.ButtonPushedFcn = createCallbackFcn(app, @CancelOrderButtonPushed, true);
            app.CancelOrderButton.Text = 'Cancel Order';
            app.RestockSwitchLabel = uilabel(app.UIFigure);
            app.RestockSwitchLabel.Text = 'Restock';
            app.RestockSwitch = uiswitch(app.UIFigure, 'slider');
            app.RestockSwitch.Items = {'Leave', 'Enter'};
            app.RestockSwitch.ValueChangedFcn = createCallbackFcn(app, @RestockSwitchValueChanged, true);
            app.RestockSwitch.Value = 'Leave';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MaestroGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end