classdef RobotsApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        estop_DobotMagician            matlab.ui.control.Button
        estop_E05L                     matlab.ui.control.Button
        q6Slider_2                     matlab.ui.control.Slider
        q6Slider_2Label                matlab.ui.control.Label
        q5Slider_2                     matlab.ui.control.Slider
        q5Slider_2Label                matlab.ui.control.Label
        q4Slider_2                     matlab.ui.control.Slider
        q4Slider_2Label                matlab.ui.control.Label
        q3Slider_2                     matlab.ui.control.Slider
        q3Slider_2Label                matlab.ui.control.Label
        q2Slider_2                     matlab.ui.control.Slider
        q2Slider_2Label                matlab.ui.control.Label
        q1Slider_2                     matlab.ui.control.Slider
        q1Slider_2Label                matlab.ui.control.Label
        q5Slider                       matlab.ui.control.Slider
        q5SliderLabel                  matlab.ui.control.Label
        q4Slider                       matlab.ui.control.Slider
        q4SliderLabel                  matlab.ui.control.Label
        q3Slider                       matlab.ui.control.Slider
        q3SliderLabel                  matlab.ui.control.Label
        q2Slider                       matlab.ui.control.Slider
        q2SliderLabel                  matlab.ui.control.Label
        q1Slider                       matlab.ui.control.Slider
        q1SliderLabel                  matlab.ui.control.Label
        ElfinE05LJointAngleqvaluesLabel  matlab.ui.control.Label
        DobotMagicianJointAngleqvaluesLabel  matlab.ui.control.Label
        YawEditField_2                 matlab.ui.control.NumericEditField
        YawEditField_2Label            matlab.ui.control.Label
        PitchEditField_2               matlab.ui.control.NumericEditField
        PitchEditField_2Label          matlab.ui.control.Label
        RoleEditField_2                matlab.ui.control.NumericEditField
        RoleEditField_2Label           matlab.ui.control.Label
        ZValueEditField_2              matlab.ui.control.NumericEditField
        ZValueEditField_2Label         matlab.ui.control.Label
        YValueEditField_2              matlab.ui.control.NumericEditField
        YValueEditField_2Label         matlab.ui.control.Label
        XValueEditField_2              matlab.ui.control.NumericEditField
        XValueEditField_2Label         matlab.ui.control.Label
        ElfinE05LInformationLabel      matlab.ui.control.Label
        DobotMagicianInformationLabel  matlab.ui.control.Label
        YValueEditField                matlab.ui.control.NumericEditField
        YValueEditFieldLabel           matlab.ui.control.Label
        YawEditField                   matlab.ui.control.NumericEditField
        YawEditFieldLabel              matlab.ui.control.Label
        PitchEditField                 matlab.ui.control.NumericEditField
        PitchEditFieldLabel            matlab.ui.control.Label
        RoleEditField                  matlab.ui.control.NumericEditField
        RoleEditFieldLabel             matlab.ui.control.Label
        ZValueEditField                matlab.ui.control.NumericEditField
        ZValueEditFieldLabel           matlab.ui.control.Label
        XValueEditField                matlab.ui.control.NumericEditField
        XValueEditFieldLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        DobotMagician % Where I will put the loaded Dobot Magician
        E05L % Where I will put the load E05_L
        modelPuma % FOR TRAINING - REMOVE
        modelUR5 % FOR TRAINING REMOVE
        qz = [0 0 0 0 0 0];
        qrPuma = [0 pi/2 -pi/2 0 0 0];
        qrUR5 = [pi/4 pi/2 -pi/2 0 0 0];
        qrE05L = [0 pi/2 -pi/2 0 0 0];
        
    end
    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             % Code to run once GUI is loaded successfully
            ax = gca;   % Initialises variable 'ax' to store current axes gca = get current axes. i.e. the graphical area for plots

            % Make, save and plot some robots            
            E05_L   % Calls E05_L class
            app.E05L = E05_L;
            % mdl_puma560 - DELETE
            % app.modelPuma = p560; DELETE
            % app.modelPuma.base = transl([0.5,0,0]); - DELETE
            app.E05L.base = transl([0.5,0,0]);
            % app.plot(app.modelPuma,app.qrPuma,'workspace',[-2 2 -2 2 -0.5
            % 2]) -DELETE
            app.plot(app.E05L, app.qrE05L, 'workspace', [-2 2 -2 2 -0.5 2]) % UNABLE TO USE PLOT IN APP DESIGNER - RECOMMENDED ON CANVAS TO PLOT ROBOT FIGURES OUTSIDE GUI
            hold on;         
            
            % PLOT OUTSIDE GUI - IN MAESTRO CLASS
            % app.modelUR5 = UR5;
            % app.modelUR5.model.base = transl([-0.5,0,0]);
            % app.modelUR5.model.animate(app.qrUR5);
            % drawnow();

            % Delete the green floor surface (need to count backwards
            % because once it is deleted the array is compressed)
            for i = size(ax.Children,1):-1:1
                if strcmp(ax.Children(i).Type,'surface')
                    delete(ax.Children(i));
                end
            end
            
            % % Turn off this load button and enable the move button
            % app.MakeLoadedPumaMoveButton.Enable = true;
            % app.MakeLoadedUR5MoveButton.Enable = true;
        end

        % Value changed function: XValueEditField
        function XValueEditFieldValueChanged(app, event)
            % value = app.XValueEditField.Value;
            % q = robot.model.getpos;
            % tr = robot.model.fkine(q);
            % tr(1,4) = tr(1,4) + 0.01;
            % newQ = robot.model.ikcon(tr, q);
            % robot.model.animate(newQ)
        end

        % Button pushed function: estop_DobotMagician
        function estop_DobotMagicianButtonPushed(app, event)
            robot.stopDobotMagician();
            app.estopped = true; % flag set to indicate emergency stop has been activated
        end

        % Button pushed function: estop_E05L
        function estop_E05LButtonPushed(app, event)
            robot.stopE05L();
            app.estopped = true; % flag set to indicate emergency stop has been activated
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.XValueEditFieldLabel = uilabel(app.UIFigure);
            app.XValueEditFieldLabel.Text = 'X Value';
            app.XValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.XValueEditField.ValueChangedFcn = createCallbackFcn(app, @XValueEditFieldValueChanged, true);
            app.ZValueEditFieldLabel = uilabel(app.UIFigure);
            app.ZValueEditFieldLabel.Text = 'Z Value';
            app.ZValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.RoleEditFieldLabel = uilabel(app.UIFigure);
            app.RoleEditFieldLabel.Text = 'Role';
            app.RoleEditField = uieditfield(app.UIFigure, 'numeric');
            app.PitchEditFieldLabel = uilabel(app.UIFigure);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.UIFigure, 'numeric');
            app.YawEditFieldLabel = uilabel(app.UIFigure);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.UIFigure, 'numeric');
            app.YValueEditFieldLabel = uilabel(app.UIFigure);
            app.YValueEditFieldLabel.Text = 'Y Value';
            app.YValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.DobotMagicianInformationLabel = uilabel(app.UIFigure);
            app.DobotMagicianInformationLabel.Text = 'Dobot Magician Information';
            app.ElfinE05LInformationLabel = uilabel(app.UIFigure);
            app.ElfinE05LInformationLabel.Text = 'Elfin E05-L Information';
            app.XValueEditField_2Label = uilabel(app.UIFigure);
            app.XValueEditField_2Label.Text = 'X Value';
            app.XValueEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.YValueEditField_2Label = uilabel(app.UIFigure);
            app.YValueEditField_2Label.Text = 'Y Value';
            app.YValueEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ZValueEditField_2Label = uilabel(app.UIFigure);
            app.ZValueEditField_2Label.Text = 'Z Value';
            app.ZValueEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.RoleEditField_2Label = uilabel(app.UIFigure);
            app.RoleEditField_2Label.Text = 'Role';
            app.RoleEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.PitchEditField_2Label = uilabel(app.UIFigure);
            app.PitchEditField_2Label.Text = 'Pitch';
            app.PitchEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.YawEditField_2Label = uilabel(app.UIFigure);
            app.YawEditField_2Label.Text = 'Yaw';
            app.YawEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.DobotMagicianJointAngleqvaluesLabel = uilabel(app.UIFigure);
            app.DobotMagicianJointAngleqvaluesLabel.Text = 'Dobot Magician Joint Angle (q values)';
            app.ElfinE05LJointAngleqvaluesLabel = uilabel(app.UIFigure);
            app.ElfinE05LJointAngleqvaluesLabel.Text = 'Elfin E05-L Joint Angle (q values)';
            app.q1SliderLabel = uilabel(app.UIFigure);
            app.q1SliderLabel.Text = 'q1';
            app.q1Slider = uislider(app.UIFigure);
            app.q2SliderLabel = uilabel(app.UIFigure);
            app.q2SliderLabel.Text = 'q2';
            app.q2Slider = uislider(app.UIFigure);
            app.q3SliderLabel = uilabel(app.UIFigure);
            app.q3SliderLabel.Text = 'q3';
            app.q3Slider = uislider(app.UIFigure);
            app.q4SliderLabel = uilabel(app.UIFigure);
            app.q4SliderLabel.Text = 'q4';
            app.q4Slider = uislider(app.UIFigure);
            app.q5SliderLabel = uilabel(app.UIFigure);
            app.q5SliderLabel.Text = 'q5';
            app.q5Slider = uislider(app.UIFigure);
            app.q1Slider_2Label = uilabel(app.UIFigure);
            app.q1Slider_2Label.Text = 'q1';
            app.q1Slider_2 = uislider(app.UIFigure);
            app.q2Slider_2Label = uilabel(app.UIFigure);
            app.q2Slider_2Label.Text = 'q2';
            app.q2Slider_2 = uislider(app.UIFigure);
            app.q3Slider_2Label = uilabel(app.UIFigure);
            app.q3Slider_2Label.Text = 'q3';
            app.q3Slider_2 = uislider(app.UIFigure);
            app.q4Slider_2Label = uilabel(app.UIFigure);
            app.q4Slider_2Label.Text = 'q4';
            app.q4Slider_2 = uislider(app.UIFigure);
            app.q5Slider_2Label = uilabel(app.UIFigure);
            app.q5Slider_2Label.Text = 'q5';
            app.q5Slider_2 = uislider(app.UIFigure);
            app.q6Slider_2Label = uilabel(app.UIFigure);
            app.q6Slider_2Label.Text = 'q6';
            app.q6Slider_2 = uislider(app.UIFigure);
            app.estop_E05L = uibutton(app.UIFigure, 'push');
            app.estop_E05L.ButtonPushedFcn = createCallbackFcn(app, @estop_E05LButtonPushed, true);
            app.estop_E05L.Text = 'ESTOP E05_L';
            app.estop_DobotMagician = uibutton(app.UIFigure, 'push');
            app.estop_DobotMagician.ButtonPushedFcn = createCallbackFcn(app, @estop_DobotMagicianButtonPushed, true);
            app.estop_DobotMagician.Text = 'ESTOP DOBOT MAGICIAN';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RobotsApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end